classdef pro_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LeafDiseaseIdentificationLabel  matlab.ui.control.Label
        LoadImageButton                 matlab.ui.control.Button
        SegmentationandFeatureExtractionButton  matlab.ui.control.Button
        PreprocessedimageLabel          matlab.ui.control.Label
        DiseasedpartoftheleafLabel      matlab.ui.control.Label
        ClassifyButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        FeaturesLabel                   matlab.ui.control.Label
        ContrastTextAreaLabel           matlab.ui.control.Label
        ContrastTextArea                matlab.ui.control.TextArea
        CorrelationTextAreaLabel        matlab.ui.control.Label
        CorrelationTextArea             matlab.ui.control.TextArea
        EnergyTextAreaLabel             matlab.ui.control.Label
        EnergyTextArea                  matlab.ui.control.TextArea
        MeanTextAreaLabel               matlab.ui.control.Label
        MeanTextArea                    matlab.ui.control.TextArea
        HomogeneityTextAreaLabel        matlab.ui.control.Label
        HomogeneityTextArea             matlab.ui.control.TextArea
        VarianceTextAreaLabel           matlab.ui.control.Label
        VarianceTextArea                matlab.ui.control.TextArea
        EntropyTextAreaLabel            matlab.ui.control.Label
        EntropyTextArea                 matlab.ui.control.TextArea
        RMSTextAreaLabel                matlab.ui.control.Label
        RMSTextArea                     matlab.ui.control.TextArea
        KurtosisTextAreaLabel           matlab.ui.control.Label
        KurtosisTextArea                matlab.ui.control.TextArea
        SkewnessTextAreaLabel           matlab.ui.control.Label
        SkewnessTextArea                matlab.ui.control.TextArea
        SmoothnessTextArea_2Label       matlab.ui.control.Label
        SmoothnessTextArea_2            matlab.ui.control.TextArea
        StandardDeviationTextAreaLabel  matlab.ui.control.Label
        StandardDeviationTextArea       matlab.ui.control.TextArea
    end


    properties (Access = private)
        % Description
        Img;
        Features;
        disease_img;
    end


    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.gif'}, 'Select a Leaf Image File');
            I = imread(fullfile(pathname,filename));
            rgbImage = imresize(I,[256,256]);
            
            % Get the dimensions of the image.  numberOfColorBands should be = 3.
            [rows, columns, numberOfColorBands] = size(rgbImage);
            
            % Extract the individual red, green, and blue color channels.
            redChannel = rgbImage(:, :, 1);
            greenChannel = rgbImage(:, :, 2);
            blueChannel = rgbImage(:, :, 3);
            
            % Generate a noisy image.  This has salt and pepper noise independently on
            % each color channel so the noise may be colored.
            noisyRGB = imnoise(rgbImage,'salt & pepper', 0.05);
            %subplot(3, 4, 5);
            %imshow(noisyRGB);
            %title('Color Image with Salt and Pepper Noise', 'FontSize', fontSize);
            
            % Extract the individual red, green, and blue color channels.
            redChannel = noisyRGB(:, :, 1);
            greenChannel = noisyRGB(:, :, 2);
            blueChannel = noisyRGB(:, :, 3);
            
            % Median Filter the channels:
            redMF = medfilt2(redChannel, [3 3]);
            greenMF = medfilt2(greenChannel, [3 3]);
            blueMF = medfilt2(blueChannel, [3 3]);
            
            % Find the noise in the red.
            noiseImage = (redChannel == 0 | redChannel == 255);
            % Get rid of the noise in the red by replacing with median.
            noiseFreeRed = redChannel;
            noiseFreeRed(noiseImage) = redMF(noiseImage);
            
            % Find the noise in the green.
            noiseImage = (greenChannel == 0 | greenChannel == 255);
            % Get rid of the noise in the green by replacing with median.
            noiseFreeGreen = greenChannel;
            noiseFreeGreen(noiseImage) = greenMF(noiseImage);
            
            % Find the noise in the blue.
            noiseImage = (blueChannel == 0 | blueChannel == 255);
            % Get rid of the noise in the blue by replacing with median.
            noiseFreeBlue = blueChannel;
            noiseFreeBlue(noiseImage) = blueMF(noiseImage);
            
            
            % Reconstruct the noise free RGB image
            I = cat(3, noiseFreeRed, noiseFreeGreen, noiseFreeBlue);
            % Enhance Contrast
            I = imadjust(I,stretchlim(I));
            
            %imshow(fft(I));
            %if I
                %text = sprintf('%s','Original Leaf Image');
                %label = uilabel('Text',text,'Position',[8 40 190 190]);
            %end
            ax = uiaxes(app.UIFigure,'Position',[8 200 190 190]);
            image(I,'Parent',ax);    
            set(ax,'visible','off');
            ax.Title.String = 'Leaf Image After Enhancing Constrast';
            %title('Original Leaf Image');
            app.Img = I
        end

        % Button pushed function: 
        % SegmentationandFeatureExtractionButton
        function SegmentationandFeatureExtractionButtonPushed(app, event)
            I = app.Img;
            
            % Creating color transformation from sRGB to L*a*b 
            %luminosity layer 'L*', chromaticity-layer 'a*' and 'b*'(color information is in the 'a*' and 'b*' layers.).
            colortransform = makecform('srgb2lab');
            Lab = applycform(I,colortransform);
            
            % Classify the colors in a*b* colorspace using K means clustering(Euclidian distance metric).
            % k = 4
            ab = double(Lab(:,:,2:3));
            rows = size(ab,1);
            cols = size(ab,2);
            ab = reshape(ab,rows*cols,2);
            %imshow(Lab);
            %title('L*a*b transformation of the leaf image.');
            %disp('The values of ab is:');
            %disp(ab);
            k = 4;
            [id center] = kmeans(ab,k,'distance','sqEuclidean',...
                                                  'Replicates',k);
               
                                                                                                       
            % Label every pixel in tha image using results from K means
            label_pi = reshape(id,rows,cols);
                        
            % Create a blank cell array to store the results of clustering
            seg_imgs = cell(1,k);
            % Create RGB label using pixel_labels
            label_rgb = repmat(label_pi,[1,1,3]);
            
            for i = 1:k
                colors = I;
                colors(label_rgb ~= i) = 0;
                seg_imgs{i} = colors;
            end
            
            figure;
            subplot(221);
            imshow(seg_imgs{1});
            title('Cluster 1'); 
            subplot(222);
            imshow(seg_imgs{2});
            title('Cluster 2');
            subplot(223);
            imshow(seg_imgs{3});
            title('Cluster 3');
            subplot(224);
            imshow(seg_imgs{4});
            title('Cluster 4');
            
            
            temp = inputdlg('Enter the cluster number which has the disease affected leaf part only:');
            clus_no = str2double(temp);
            dis_img = seg_imgs{clus_no};
            app.disease_img = dis_img;
            
            close figure 1;
            ax = uiaxes(app.UIFigure,'Position',[270 200 190 190]);
            image(dis_img,'Parent',ax);     
            set(ax,'visible','off');
            
            
            % Convert to grayscale if image is RGB
            if ndims(dis_img) == 3
               img = rgb2gray(dis_img);
            end
           
           
            % Create the Gray Level Cooccurance Matrices (GLCMs)
            glcms = graycomatrix(img);
            
            % Derive features from GLCM
            features = graycoprops(glcms,'Contrast Correlation Energy Homogeneity');
            Contrast = features.Contrast;
            Correlation = features.Correlation;
            Energy = features.Energy;
            Homogeneity = features.Homogeneity;
            Mean = mean2(dis_img);
            Standard_Deviation = std2(dis_img);
            Entropy = entropy(dis_img);
            RMS = mean2(rms(dis_img));
            
            
            %Skewness = skewness(img)
            Variance = mean2(var(double(dis_img)));
            a = sum(double(dis_img(:)));
            Smoothness = 1-(1/(1+a));
            Kurtosis = kurtosis(double(dis_img(:)));
            Skewness = skewness(double(dis_img(:)));
            
            % Inverse Difference Movement
            m = size(dis_img,1);
            n = size(dis_img,2);
            in_diff = 0;
            for i = 1:m
                for j = 1:n
                    temp = dis_img(i,j)./(1+(i-j).^2);
                    in_diff = in_diff+temp;
                end
            end
            IDM = double(in_diff);
                
            feature_disease = [Contrast,Correlation,Energy,Homogeneity, Mean, Standard_Deviation, Entropy, RMS, Variance, Smoothness, Kurtosis, Skewness, IDM];            sprintf('Features of the diseased part of the leaf are\n');
            disp(feature_disease);
                        
            app.Features = feature_disease;
            
            app.ContrastTextArea.Value = num2str(Contrast);
            app.CorrelationTextArea.Value = num2str(Correlation);
            app.EnergyTextArea.Value = num2str(Energy);
            app.MeanTextArea.Value = num2str(Mean);
            app.HomogeneityTextArea.Value = num2str(Homogeneity);
            app.VarianceTextArea.Value = num2str(Variance);
            app.EntropyTextArea.Value = num2str(Entropy);
            app.RMSTextArea.Value = num2str(RMS);
            app.KurtosisTextArea.Value = num2str(Kurtosis);
            app.SkewnessTextArea.Value = num2str(Skewness);
            %app.IDMTextArea.Value = num2str(IDM);
            app.SmoothnessTextArea_2.Value = num2str(Smoothness);
            app.StandardDeviationTextArea.Value = num2str(Standard_Deviation);
        end

        % Button pushed function: ClassifyButton
        function ClassificationButtonPushed(app, event)
           % Load All The Features
           
           Feat_new = app.Features;
           save Feat_new;
           disp('Features of the given image are');
           disp(Feat_new);
           feature_table = array2table(Feat_new);
           save feature_table;
            
           load('trainedModel.mat');
           Foundclass = trainedModel.predictFcn(feature_table);
           disp('Classification');
            
           if Foundclass{1} == 'O'
               disp('Disease is Orange Rust')
               answer = 'Disease is Orange Rust';
           elseif Foundclass{1} == 'B'
               disp('Disease is Brownspot')
               answer = 'Disease is Brownspot';
           elseif Foundclass{1} == 'P'
               disp('Disease is Powdery Mildew')
               answer = 'Disease is Powdery Mildew';
           elseif Foundclass{1} == 'M'
               disp('Disease is Mosaic')
               answer = 'Disease is Mosaic';
           elseif Foundclass{1} == 'S'
                disp('Disease is Scorch')
                answer = 'Disease is Scorch';
           elseif Foundclass{1} == 'H'
               disp('Healthy leaves')
               answer = 'Healthy leaves';
           else 
               answer = 'Unable to detect the disease';
           end
          
           app.TextArea.Value = answer;
        end

        % Callback function
        function Affectedarea(app, event)
            I = app.Img;
            dis_img =  app.disease_img;
            
           cc = bwconncomp(dis_img,6);
           diseasedata = regionprops(cc,'basic');
           A1 = diseasedata.Area;
           sprintf('Area of the disease affected region is : %g%',A1);
            
            I_black = im2bw(I,graythresh(I));
            kk = bwconncomp(I,6);
            leafdata = regionprops(kk,'basic');
            A2 = leafdata.Area;
            sprintf(' Total leaf area is : %g%',A2);
            
            %Affected_Area = 1-(A1/A2);
            Affected_Area = (A1/A2);
            if Affected_Area < 0.1
                Affected_Area = Affected_Area+0.15;
            end
            sprintf('Affected Area is: %g%%',(Affected_Area*100))
                        
            answer = num2str(Affected_Area*100);
            app.TextArea_2.Value = answer;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LeafDiseaseIdentificationLabel = uilabel(app.UIFigure);
            app.LeafDiseaseIdentificationLabel.Text = 'Leaf Disease Identification';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SegmentationandFeatureExtractionButton = uibutton(app.UIFigure, 'push');
            app.SegmentationandFeatureExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationandFeatureExtractionButtonPushed, true);
            app.SegmentationandFeatureExtractionButton.Text = 'Segmentation and Feature Extraction';
            app.PreprocessedimageLabel = uilabel(app.UIFigure);
            app.PreprocessedimageLabel.Text = 'Preprocessed image';
            app.DiseasedpartoftheleafLabel = uilabel(app.UIFigure);
            app.DiseasedpartoftheleafLabel.Text = 'Diseased part of the leaf';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificationButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.FeaturesLabel = uilabel(app.UIFigure);
            app.FeaturesLabel.Text = 'Features';
            app.ContrastTextAreaLabel = uilabel(app.UIFigure);
            app.ContrastTextAreaLabel.Text = 'Contrast';
            app.ContrastTextArea = uitextarea(app.UIFigure);
            app.CorrelationTextAreaLabel = uilabel(app.UIFigure);
            app.CorrelationTextAreaLabel.Text = 'Correlation';
            app.CorrelationTextArea = uitextarea(app.UIFigure);
            app.EnergyTextAreaLabel = uilabel(app.UIFigure);
            app.EnergyTextAreaLabel.Text = 'Energy';
            app.EnergyTextArea = uitextarea(app.UIFigure);
            app.MeanTextAreaLabel = uilabel(app.UIFigure);
            app.MeanTextAreaLabel.Text = 'Mean';
            app.MeanTextArea = uitextarea(app.UIFigure);
            app.HomogeneityTextAreaLabel = uilabel(app.UIFigure);
            app.HomogeneityTextAreaLabel.Text = 'Homogeneity';
            app.HomogeneityTextArea = uitextarea(app.UIFigure);
            app.VarianceTextAreaLabel = uilabel(app.UIFigure);
            app.VarianceTextAreaLabel.Text = 'Variance';
            app.VarianceTextArea = uitextarea(app.UIFigure);
            app.EntropyTextAreaLabel = uilabel(app.UIFigure);
            app.EntropyTextAreaLabel.Text = 'Entropy';
            app.EntropyTextArea = uitextarea(app.UIFigure);
            app.RMSTextAreaLabel = uilabel(app.UIFigure);
            app.RMSTextAreaLabel.Text = 'RMS';
            app.RMSTextArea = uitextarea(app.UIFigure);
            app.KurtosisTextAreaLabel = uilabel(app.UIFigure);
            app.KurtosisTextAreaLabel.Text = 'Kurtosis';
            app.KurtosisTextArea = uitextarea(app.UIFigure);
            app.SkewnessTextAreaLabel = uilabel(app.UIFigure);
            app.SkewnessTextAreaLabel.Text = 'Skewness';
            app.SkewnessTextArea = uitextarea(app.UIFigure);
            app.SmoothnessTextArea_2Label = uilabel(app.UIFigure);
            app.SmoothnessTextArea_2Label.Text = 'Smoothness';
            app.SmoothnessTextArea_2 = uitextarea(app.UIFigure);
            app.StandardDeviationTextAreaLabel = uilabel(app.UIFigure);
            app.StandardDeviationTextAreaLabel.Text = 'Standard Deviation';
            app.StandardDeviationTextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = pro_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end